classdef FKin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EndEffectoryPositionEditField  matlab.ui.control.NumericEditField
        EndEffectoryPositionEditFieldLabel  matlab.ui.control.Label
        CreateRobotButton              matlab.ui.control.Button
        EndEffectorxPositionEditField  matlab.ui.control.NumericEditField
        EndEffectorxPositionEditFieldLabel  matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = DrawBot; % This is a Serial Link Created robot 
        jointangles= [ 0 0]; % these are the joint angles of the robot
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderChanged(app, event)
            app. jointangles (1) = app. Joint1Slider.Value;
            %calculate the forward kinematics
            T = app. robot. fkine([app. jointangles(1), app. jointangles(2) ]);%transformation matrix
            T = T. T;%changes obj type to standard numerical matrix
            %Update the position values of the end-effector
            app. EndEffectorxPositionEditField.Value = T(1,4);%update the x value of the end effector
            app. EndEffectoryPositionEditField.Value = T(2,4) ;%update the y value of end effector

            app.robot.plot(app.jointangles * pi/180)
        end

        % Value changed function: Joint2Slider
        function Joint2SliderChanged(app, event)
            app. jointangles (2) = app. Joint2Slider.Value;
            %calculate the forward kinematics
            T = app. robot. fkine([app. jointangles(1), app. jointangles(2) ]);%transformation matrix
            T = T. T;%changes obj type to standard numerical matrix
            %Update the position values of the end-effector
            app. EndEffectorxPositionEditField.Value = T(1,4);%update the x value of the end effector
            app. EndEffectoryPositionEditField.Value = T(2,4) ;%update the y value of end effector
            
            app.robot.plot(app.jointangles * pi/180)
        end

        % Value changed function: EndEffectorxPositionEditField
        function EndEffectorPosChanged(app, event)
            Value = app.EndEffectorxPositionEditField.Value;
        end

        % Button pushed function: CreateRobotButton
        function Create_Callback(app, event)
            app.robot.plot(app.jointangles * pi/180)
            axis([-10, 25, -5, 35, 0, 15])
            view(30, 45)
        end

        % Value changed function: EndEffectoryPositionEditField
        function endEffectorPosYChanged(app, event)
            value = app.EndEffectoryPositionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'UIFigure';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-25 165];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-160 70];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderChanged, true);
            app.EndEffectorxPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectorxPositionEditFieldLabel.Text = 'End Effector x-Position';
            app.EndEffectorxPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectorxPositionEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorPosChanged, true);
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.EndEffectoryPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectoryPositionEditFieldLabel.Text = 'End Effector y-Position';
            app.EndEffectoryPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectoryPositionEditField.ValueChangedFcn = createCallbackFcn(app, @endEffectorPosYChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FKin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end